classdef Program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DFTlengthNLabel               matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        HanningButton                 matlab.ui.control.Button
        HammingButton                 matlab.ui.control.Button
        RectangularButton             matlab.ui.control.Button
        PenggunaanProgramButton       matlab.ui.control.Button
        UbahFrequencydibawahiniLabel  matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        Frequency5Label               matlab.ui.control.Label
        Frequency4Label               matlab.ui.control.Label
        Frequency3Label               matlab.ui.control.Label
        Frequency2Label               matlab.ui.control.Label
        Frequency1Label               matlab.ui.control.Label
        EditField_5                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField                     matlab.ui.control.EditField
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EditField, EditField_2, EditField_2, 
        % EditField_3, EditField_4, EditField_5, Slider
        function EditFieldValueChanged(app, event)
            global f1
            global f2
            global f3
            global f4
            global f5  
            
            
            value1 = app.EditField.Value;
            f1 = str2double(value1);
            value2 = app.EditField_2.Value;
            f2 = str2double(value2);
            value3 = app.EditField_3.Value;
            f3 = str2double(value3);
            value4 = app.EditField_4.Value;
            f4 = str2double(value4);
            value5 = app.EditField_5.Value;
            f5 = str2double(value5);
            
            
            Fs = 10000;                % Sample rate in Hz
            duration = 0.0025;         % Signal duration in seconds
            t = 0:1/Fs:duration-1/Fs;  % Time vector
            x = sin(2.*pi.*f1.*t) + sin(2.*pi.*f2.*t) + sin(2.*pi.*f3.*t)+ sin(2.*pi.*f4.*t) + sin(2.*pi.*f5.*t);
            % DFT Length
            N = round(app.Slider.Value);
            % Compute frequency vectors
            f = (0:N-1) * Fs / N;

            % Compute DFT with rectangular windowing
            windowRect = rectwin(length(x));
            xWindowedRect = x .* windowRect.';
            X_rect = fft(xWindowedRect, N);

            % Compute DFT with Hamming windowing
            windowHamming = hamming(length(x));
            xWindowedHamming = x .* windowHamming.';
            X_hamming = fft(xWindowedHamming, N);
            
            % COmpute DFT with hanning windowing
            windowHanning = hanning(length(x));
            xWindowedHanning = x .* windowHanning.';
            X_hanning = fft(xWindowedHanning, N);
                    
            plot(app.UIAxes, f, abs(X_rect));
            plot(app.UIAxes_2, f, abs(X_hamming));
            plot(app.UIAxes_3, f, abs(X_hanning));
            plot(app.UIAxes2, x);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Home;
            app.delete;
        end

        % Button pushed function: PenggunaanProgramButton
        function PenggunaanProgramButtonPushed(app, event)
            Penggunaan;
        end

        % Button pushed function: RectangularButton
        function RectangularButtonPushed(app, event)
            rect;
        end

        % Button pushed function: HammingButton
        function HammingButtonPushed(app, event)
            hamm;
        end

        % Button pushed function: HanningButton
        function HanningButtonPushed(app, event)
            hann;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Rectangular Windowing ')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Hamming Windowing')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Magnitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Hanning Windowing')
            xlabel(app.UIAxes_3, 'Frequency (Hz)')
            ylabel(app.UIAxes_3, 'Magnitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Signal representation before applying DFT')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = '0';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2.Value = '0';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_3.Value = '0';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_4.Value = '0';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_5.Value = '0';
            app.Frequency1Label = uilabel(app.UIFigure);
            app.Frequency1Label.Text = 'Frequency 1';
            app.Frequency2Label = uilabel(app.UIFigure);
            app.Frequency2Label.Text = 'Frequency 2';
            app.Frequency3Label = uilabel(app.UIFigure);
            app.Frequency3Label.Text = 'Frequency 3';
            app.Frequency4Label = uilabel(app.UIFigure);
            app.Frequency4Label.Text = 'Frequency 4';
            app.Frequency5Label = uilabel(app.UIFigure);
            app.Frequency5Label.Text = 'Frequency 5';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [25 1028];
            app.Slider.MajorTicks = [25 128 225 325 425 525 625 725 825 925 1028];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Slider.Value = 25;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'home.png';
            app.Button.Text = '';
            app.UbahFrequencydibawahiniLabel = uilabel(app.UIFigure);
            app.UbahFrequencydibawahiniLabel.Text = {'Ubah Frequency dibawah ini :'; ''};
            app.PenggunaanProgramButton = uibutton(app.UIFigure, 'push');
            app.PenggunaanProgramButton.ButtonPushedFcn = createCallbackFcn(app, @PenggunaanProgramButtonPushed, true);
            app.PenggunaanProgramButton.Text = 'Penggunaan Program';
            app.RectangularButton = uibutton(app.UIFigure, 'push');
            app.RectangularButton.ButtonPushedFcn = createCallbackFcn(app, @RectangularButtonPushed, true);
            app.RectangularButton.Text = 'Rectangular';
            app.HammingButton = uibutton(app.UIFigure, 'push');
            app.HammingButton.ButtonPushedFcn = createCallbackFcn(app, @HammingButtonPushed, true);
            app.HammingButton.Text = 'Hamming';
            app.HanningButton = uibutton(app.UIFigure, 'push');
            app.HanningButton.ButtonPushedFcn = createCallbackFcn(app, @HanningButtonPushed, true);
            app.HanningButton.Text = 'Hanning';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'soal2.png';
            app.DFTlengthNLabel = uilabel(app.UIFigure);
            app.DFTlengthNLabel.Text = 'DFT length (N)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end